classdef REVENGENCE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        Q5Slider                      matlab.ui.control.Slider
        Q5SliderLabel                 matlab.ui.control.Label
        Q4Slider                      matlab.ui.control.Slider
        Q4SliderLabel                 matlab.ui.control.Label
        Q3Slider                      matlab.ui.control.Slider
        Q3SliderLabel                 matlab.ui.control.Label
        Q2Slider                      matlab.ui.control.Slider
        Q2SliderLabel                 matlab.ui.control.Label
        Q1Slider                      matlab.ui.control.Slider
        Q1SliderLabel                 matlab.ui.control.Label
        GrabButton                    matlab.ui.control.StateButton
        PAUSEButton                   matlab.ui.control.StateButton
        ZSlider                       matlab.ui.control.Slider
        ZSliderLabel                  matlab.ui.control.Label
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        LaunchButton                  matlab.ui.control.Button
        LaunchOptionsButtonGroup      matlab.ui.container.ButtonGroup
        SimulationButton              matlab.ui.control.ToggleButton
        RobotButton                   matlab.ui.control.ToggleButton
        EMERGENCYSTOPButton           matlab.ui.control.Button
        RobotOptionsButtonGroup       matlab.ui.container.ButtonGroup
        SliderButton_2                matlab.ui.control.ToggleButton
        SensorControlButton_2         matlab.ui.control.ToggleButton
        SimulationOptionsButtonGroup  matlab.ui.container.ButtonGroup
        SliderButton                  matlab.ui.control.ToggleButton
        DummyDataButton               matlab.ui.control.ToggleButton
        SensorControlButton           matlab.ui.control.ToggleButton
        InitialiseDobotButton         matlab.ui.control.Button
        InitialiseSensorButton        matlab.ui.control.Button
        plot                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % % place your class properties here
        % modelPuma % Where I will put the loaded puma
        % modelUR5 % Where I will put the loaded UR5
        % qz = [0     0     0     0     0     0];
        % qrPuma = [0	pi/2  -pi/2	0	0	0];
        % qrUR5 = [pi/4	pi/2  -pi/2	0	0	0];
        robot
        TM5robot
        E_Stop = 0;
        Pause_Stop = 0;
        %workspace = [-1 1 -1 1 0 1];
        workspace = [-3,3,-3,3,-0.6,4];
        speed = 10;
        T1 = eye(4);
        T2 = eye(4);
        q1
        q2
        x_slider = 0.0;
        y_slider = 0.0;
        z_slider = 0.0;
        q_link_1
        q_link_2
        q_link_3
        q_link_4
        q_link_5
        r
        b


    end

    properties (Access = public)
        %ROS INIT properties
        DobotMagician
        SafetyStatePublisher
        SafetyStateMsg
        SafetyStatusSubscriber
        CurrentSafetyStatus
        JointStateSubscriber

    end

    properties (Access = public)
        %Sensor INIT properties
        pipe
        cfg
        align_to
        align
        colorizer
        profile
        dev
        name

    end


    methods (Access = public)


        %% ----DUMMY SENSOR DATA----
        %Inputs: None
        function T = Dummy_Sensor(app)
            persistent count
            if isempty(count)
                count = 1;
            end
            targetPoses = [transl(0.1, -0.1, 0.1);


            transl(0.2, 0.2, 0.2);


            transl(0.3, 0.4, 0.1);

            transl(-0.4, 0.2, 0.3);

            transl(0.7, -0.4, 0.1);

            transl(0.9, 0.6, 0.6);

            transl(-0.1, 0.7, 0.45);

            transl(0.5, -0.4, 0.1);

            transl(0.25, 0.65, 0.7);

            transl(-0.4, 0.7, 0.25);

            transl(0.3, 0.5, 0.8);

            transl(0.3, -0.1, 0.1);

            transl(0.4, 0.4, 0.4);

            transl(-0.35, 0.7, 0.1);

            transl(0.5, -0.3, 0.5);

            transl(-0.3, 0.4, 0.1);

            transl(0.2, -0.6, 0.45);

            transl(0.3, 0.7, 0.3);

            transl(-0.4, 0.45, 0.15);

            transl(0.35, -0.55, 0.6);

            transl(0.7, 0.8, 0.3);

            transl(0.6, 0.65, 0.8);

            transl(-0.1, 0.3, 0.55);

            transl(0.2, 0.2, 0.2);

            transl(-0.3, 0.6, 0.2);

            transl(0.3, -0.4, 0.6);

            transl(0.35, 0.45, 0.15);

            transl(0.65, 0.25, 0.5);

            transl(0.1, 0.1, 0.1);

            transl(0.35, 0.4, 0.15);

            transl(-0.6, 0.7, 0.1);

            transl(0.6, 0.5, 0.5);

            transl(-0.3, 0.45, 0.25);

            transl(0.75, 0.65, 0.45);

            transl(0.2, -0.6, 0.35);

            transl(0.3, -0.1, 0.1);

            transl(0.45, 0.45, 0.5);

            transl(-0.25, -0.3, 0.4);

            transl(0.3, 0.5, 0.8);

            transl(0.3, -0.1, 0.1);

            transl(0.4, 0.4, 0.4);


            ];


            T = targetPoses(count:count+3,:);

            if count <= size(targetPoses, 1)-4
                count = count + 4;
            end
        end
        %Outputs: T Target Poses
        %------------------

        function TB = Dummy_SensorB(app)
            app.b = RMRC_test(app.TM5robot);
            persistent count
            if isempty(count)
                count = 1;
            end

            targetPoses = [app.b(1:5, 1:6);
                app.b(6:10, 1:6);
                app.b(11:15, 1:6);
                app.b(16:20, 1:6);
                app.b(21:25, 1:6);
                app.b(26:30, 1:6);
                app.b(31:35, 1:6);
                app.b(36:40, 1:6);
                app.b(41:45, 1:6)
                app.b(46:49, 1:6)];


            TB = targetPoses([count:count+4],:);
            if count >= 41
                count = 1;
            elseif count <= size(targetPoses, 1)-4
                count = count + 5;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            app.robot = DobotMagicianSim;
            app.TM5robot = TM5;
            app.r = zeros(1,6);
            app.q1 = app.robot.model.ikcon(transl(0,0,0));
            app.q2 = app.robot.model.ikcon(transl(0,0,0));
            app.TM5robot.model.base = transl([-0.5,0,0]);
            app.robot.model.base = transl([0.5,0,0]);
            hold on
            PlaceObject('fireExtinguisherElevated.ply',[2,0,-0.5]);
            % Place emergency button
            PlaceObject('emergencyStopButton.ply',[2.5,1,0]);
            % Position of operator
            PlaceObject('personMaleOld.ply',[2.5,0,-0.5]);
            % Baby outside the fence
            PlaceObject('baby.ply',[-2.5,0,-0.5]);
            % Place table
            PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0,0,-0.5]);

            % Place Fences
            h_1 = PlaceObject('fenceFinal.ply',[2,0,-0.6]);
            verts = [get(h_1,'Vertices'), ones(size(get(h_1,'Vertices'),1),1)] * trotz(pi/2);
            verts(:,1) = verts(:,1) * 8;
            set(h_1,'Vertices',verts(:,1:3))

            h_2 = PlaceObject('fenceFinal.ply',[-2,0,-0.5]);
            verts = [get(h_2,'Vertices'), ones(size(get(h_2,'Vertices'),1),1)] * trotz(pi/2);
            verts(:,1) = verts(:,1) * 8;
            set(h_2,'Vertices',verts(:,1:3))

            h_3 = PlaceObject('fenceFinal.ply',[0,0,-0.5]);
            verts = [get(h_3,'Vertices'), ones(size(get(h_3,'Vertices'),1),1)];
            verts(:,2) = verts(:,2) * 8;
            verts(:,1) = verts(:,1) + 2;
            set(h_3,'Vertices',verts(:,1:3))

            h_4 = PlaceObject('fenceFinal.ply',[0,0,-0.5]);
            verts = [get(h_4,'Vertices'), ones(size(get(h_4,'Vertices'),1),1)];
            verts(:,2) = verts(:,2) * 8;
            verts(:,1) = verts(:,1) - 2;
            set(h_4,'Vertices',verts(:,1:3))


        end

        % Callback function
        function MakeLoadedPumaMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrPuma,app.qz,20);
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelPuma.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Callback function
        function MakeLoadedUR5MoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5,app.qz,20);
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Button pushed function: InitialiseSensorButton
        function InitSensor(app, event)
            app.pipe = realsense.pipeline();
            app.cfg = realsense.config();
            app.cfg.enable_stream(realsense.stream.color, 848, 480, realsense.format.rgb8);
            app.cfg.enable_stream(realsense.stream.depth, 848, 480, realsense.format.z16);
            app.align_to = realsense.stream.color;
            app.align = realsense.align(app.align_to);
            app.colorizer = realsense.colorizer();
            app.profile = app.pipe.start(app.cfg);
            app.dev = app.profile.get_device();
            app.name = app.dev.get_info(realsense.camera_info.name);
            for i = 1:5
                fs = app.pipe.wait_for_frames();
            end

        end

        % Button pushed function: InitialiseDobotButton
        function InitRobot(app, event)
            rosshutdown;
            rosinit('192.168.27.1');
            app.DobotMagician = app.DobotMagician();

            [app.SafetyStatePublisher,app.SafetyStateMsg] = rospublisher('/dobot_magician/target_safety_status');
            app.SafetyStateMsg.Data = 2;
            send(app.SafetyStatePublisher,app.SafetyStateMsg);

            app.SafetyStatusSubscriber = rossubscriber('/dobot_magician/safety_status');

            %app.CurrentSafetyStatus = app.SafetyStatusSubscriber.LatestMessage.Data;

            app.JointStateSubscriber = rossubscriber('/dobot_magician/joint_states');
            toolStateSubscriber = rossubscriber('/dobot_magician/tool_state');

        end

        % Button down function: RobotOptionsButtonGroup
        function RobotOptionsButtonGroupButtonDown(app, event)

        end

        % Button pushed function: LaunchButton
        function Launch(app, event)
            app.E_Stop = 0;
            app.Pause_Stop = 0;
            %app.b = RMRC_test(app.TM5robot);


            if app.SimulationButton.Value
                %%---------SIMULATION-----------------------------------
                while app.E_Stop == 0
                    %figure(1)
                    while app.Pause_Stop == 0
                        steps = 5;
                        qMatrixTM5 = Dummy_SensorB(app);

                        if app.SensorControlButton.Value
                            app.T2=transl(Sensor_Data(app.pipe,app.colorizer,app.profile,app.dev,app.name,app.align));
                            app.q2 = app.robot.model.ikcon(app.T2);
                        elseif app.DummyDataButton.Value
                            app.T2=Dummy_Sensor(app);
                            app.q2 = app.robot.model.ikcon(app.T2);
                        elseif app.SliderButton.Value


                        end

                        qMatrix = jtraj(app.q1,app.q2,steps);
                        %app.robot.model.plot(qMatrix,'workspace', app.workspace, 'trail','r-')
                        %app.TM5robot.model.plot(qMatrixTM5,'workspace',app.workspace,'scale', 0.5);
                         app.robot.model.animate(qMatrix);
                         app.TM5robot.model.animate(qMatrixTM5);
                         pause(0.1)


                        app.q1 =app.q2;
                        app.T1=app.T2;

                        app.Q1Slider.Value = app.q2(1,1);
                        app.Q2Slider.Value = app.q2(1,2);
                        app.Q3Slider.Value = app.q2(1,3);
                        app.Q4Slider.Value = app.q2(1,4);
                        app.Q5Slider.Value = app.q2(1,5);


                    end
                    pause(0.1)
                end
                %%---------ROBOT-----------------------------------
            elseif app.RobotButton.Value
                app.robot.model.base = transl([0,0,0]);
                [safetyStatePublisher,safetyStateMsg] = rospublisher('/dobot_magician/target_safety_status');
                safetyStateMsg.Data = 4;
                send(safetyStatePublisher,safetyStateMsg);


                %currentSafetyStatus = safetyStatusSubscriber.LatestMessage.Data;
                while app.E_Stop == 0
                    %figure(1)
                    while app.Pause_Stop == 0


                        if app.SliderButton_2.Value
                            pause(1)
                            % app.T2=transl(app.x_slider,app.y_slider,app.z_slider);

                        elseif app.SensorControlButton_2.Value
                            pause(2.5)
                            T = Sensor_Data(app.pipe,app.colorizer,app.profile,app.dev,app.name,app.align);
                            app.T2=transl(T);
                            app.q2 = app.robot.model.ikcon(app.T2);

                        end


                        jointTarget = app.q2(1:4)
                        [targetJointTrajPub,targetJointTrajMsg] = rospublisher('/dobot_magician/target_joint_states');
                        trajectoryPoint = rosmessage("trajectory_msgs/JointTrajectoryPoint");
                        trajectoryPoint.Positions = jointTarget;
                        targetJointTrajMsg.Points = trajectoryPoint;
                        send(targetJointTrajPub,targetJointTrajMsg);
                        app.Q1Slider.Value = app.q2(1,1);
                        app.Q2Slider.Value = app.q2(1,2);
                        app.Q3Slider.Value = app.q2(1,3);
                        app.Q4Slider.Value = app.q2(1,4);
                        app.Q5Slider.Value = app.q2(1,5);
                    end
                    pause(0.1)

                end

            end

        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)

            app.E_Stop = 1;
            app.Pause_Stop = 1;
            if app.RobotButton.Value
                [safetyStatePublisher,safetyStateMsg] = rospublisher('/dobot_magician/target_safety_status');
                safetyStateMsg.Data = 3;
                send(safetyStatePublisher,safetyStateMsg);
            end
            return
        end

        % Callback function
        function RESUMEButtonPushed(app, event)
            app.E_Stop = 0;
        end

        % Value changed function: XSlider, YSlider, ZSlider
        function XSliderValueChanged(app, event)
            app.x_slider = app.XSlider.Value;
            app.y_slider = app.YSlider.Value;
            app.z_slider = app.ZSlider.Value;

            app.T2=transl(app.x_slider,app.y_slider,app.z_slider);
            app.q2 = app.robot.model.ikcon(app.T2);

        end

        % Callback function
        function YSliderValueChanged(app, event)


        end

        % Value changed function: PAUSEButton
        function PAUSEButtonValueChanged(app, event)
            value = app.PAUSEButton.Value;
            if value == 1
                app.Pause_Stop = 1;
            elseif value == 0
                app.Pause_Stop = 0;
            end

        end

        % Value changed function: GrabButton
        function GrabButtonValueChanged(app, event)
            value = app.GrabButton.Value;
            if value == 1
                [toolStatePub, toolStateMsg] = rospublisher('/dobot_magician/target_tool_state');
                toolStateMsg.Data = [1 1];
                send(toolStatePub,toolStateMsg);
            elseif value == 0
                [toolStatePub, toolStateMsg] = rospublisher('/dobot_magician/target_tool_state');
                toolStateMsg.Data = [1 0];
                send(toolStatePub,toolStateMsg);
            end

        end

        % Value changed function: Q1Slider, Q2Slider, Q3Slider, Q4Slider, 
        % ...and 1 other component
        function Q1SliderValueChanged(app, event)
            app.q_link_1 = app.Q1Slider.Value;
            app.q_link_2 = app.Q2Slider.Value;
            app.q_link_3 = app.Q3Slider.Value;
            app.q_link_4 = app.Q4Slider.Value;
            app.q_link_5 = app.Q5Slider.Value;

            app.q2 = [app.q_link_1,app.q_link_2, app.q_link_3, app.q_link_4, app.q_link_5];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.plot.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.plot.ZTick = [0 0.5 1];
            app.plot.Tag = 'axes1';
            app.InitialiseSensorButton = uibutton(app.figure1, 'push');
            app.InitialiseSensorButton.ButtonPushedFcn = createCallbackFcn(app, @InitSensor, true);
            app.InitialiseSensorButton.Text = 'Initialise Sensor';
            app.InitialiseDobotButton = uibutton(app.figure1, 'push');
            app.InitialiseDobotButton.ButtonPushedFcn = createCallbackFcn(app, @InitRobot, true);
            app.InitialiseDobotButton.Text = 'Initialise Dobot';
            app.SimulationOptionsButtonGroup = uibuttongroup(app.figure1);
            app.SimulationOptionsButtonGroup.Title = 'Simulation Options';
            app.SensorControlButton = uitogglebutton(app.SimulationOptionsButtonGroup);
            app.SensorControlButton.Text = 'Sensor Control';
            app.SensorControlButton.Value = true;
            app.DummyDataButton = uitogglebutton(app.SimulationOptionsButtonGroup);
            app.DummyDataButton.Text = 'Dummy Data';
            app.SliderButton = uitogglebutton(app.SimulationOptionsButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.RobotOptionsButtonGroup = uibuttongroup(app.figure1);
            app.RobotOptionsButtonGroup.Title = 'Robot Options';
            app.RobotOptionsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @RobotOptionsButtonGroupButtonDown, true);
            app.SensorControlButton_2 = uitogglebutton(app.RobotOptionsButtonGroup);
            app.SensorControlButton_2.Text = 'Sensor Control';
            app.SensorControlButton_2.Value = true;
            app.SliderButton_2 = uitogglebutton(app.RobotOptionsButtonGroup);
            app.SliderButton_2.Text = 'Slider';
            app.EMERGENCYSTOPButton = uibutton(app.figure1, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.LaunchOptionsButtonGroup = uibuttongroup(app.figure1);
            app.LaunchOptionsButtonGroup.Title = 'Launch Options';
            app.RobotButton = uitogglebutton(app.LaunchOptionsButtonGroup);
            app.RobotButton.Text = 'Robot';
            app.RobotButton.Value = true;
            app.SimulationButton = uitogglebutton(app.LaunchOptionsButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.LaunchButton = uibutton(app.figure1, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @Launch, true);
            app.LaunchButton.Text = 'Launch';
            app.XSliderLabel = uilabel(app.figure1);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.figure1);
            app.XSlider.Limits = [0 0.3];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.Value = 0.15;
            app.YSliderLabel = uilabel(app.figure1);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.figure1);
            app.YSlider.Limits = [-0.3 0.3];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.figure1);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.figure1);
            app.ZSlider.Limits = [0 0.3];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.PAUSEButton = uibutton(app.figure1, 'state');
            app.PAUSEButton.ValueChangedFcn = createCallbackFcn(app, @PAUSEButtonValueChanged, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.GrabButton = uibutton(app.figure1, 'state');
            app.GrabButton.ValueChangedFcn = createCallbackFcn(app, @GrabButtonValueChanged, true);
            app.GrabButton.Text = 'Grab';
            app.Q1SliderLabel = uilabel(app.figure1);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.figure1);
            app.Q1Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.figure1);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.figure1);
            app.Q2Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q3SliderLabel = uilabel(app.figure1);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.figure1);
            app.Q3Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q4SliderLabel = uilabel(app.figure1);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.figure1);
            app.Q4Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q5SliderLabel = uilabel(app.figure1);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.figure1);
            app.Q5Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REVENGENCE(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end