classdef GUI_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        MoveDobotButton  matlab.ui.control.Button
        q3Slider         matlab.ui.control.Slider
        q3SliderLabel    matlab.ui.control.Label
        q2Slider         matlab.ui.control.Slider
        q2SliderLabel    matlab.ui.control.Label
        q1Slider         matlab.ui.control.Slider
        q1SliderLabel    matlab.ui.control.Label
        LoadDobotButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelDobotMagicianSim
        qz = [0     0     0     0     0 ];
        qrDobotMagicianSim = [0 0 pi/2 pi/2 0]
        
        qcDobotMagicianSim = [-pi/2 0 pi/2 pi/2 0]% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDobotButton
        function LoadDobotButtonPushed(app, event)
            axes(app.UIAxes);
            cla(app.UIAxes)

            ax = gca;
            app.modelDobotMagicianSim = DobotMagicianSim;
            app.modelDobotMagicianSim.model.base = transl([0,0,0]);
            app.modelDobotMagicianSim.model.animate(app.qrDobotMagicianSim);
            drawnow();
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Transfer all the children across to the uiaxes parent EXCEPT
            % the green floors
            while 0 < length(ax.Children)                
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))
            
            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];
            
            % Turn off this load button and enable the move button

            app.LoadDobotButton.Enable = false;
            app.MoveDobotButton.Enable = true;
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            app.qcDobotMagicianSim(1) = app.q1Slider.Value;
            app.modelDobotMagicianSim.model.animate(app.qcDobotMagicianSim);
            drawnow;
          
            
        end

        % Button pushed function: MoveDobotButton
        function MoveDobotButtonPushed(app, event)
            qMatrix = jtraj(app.qrDobotMagicianSim,app.qz,20);
            for robotStepIndex = 1:size(qMatrix,1)

                app.modelDobotMagicianSim.model.animate(qMatrix(robotStepIndex,:));
                drawnow;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadDobotButton = uibutton(app.UIFigure, 'push');
            app.LoadDobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDobotButtonPushed, true);
            app.LoadDobotButton.Text = 'Load Dobot';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-1.5707963267949 1.5707963267949];
            app.q1Slider.MajorTicks = [-1.5707963267949 -0.785398163397448 0 0.785398163397448 1.5707963267949];
            app.q1Slider.MajorTickLabels = {'-pi/2', '-pi/4', '0', 'pi/4', 'pi/2'};
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.MoveDobotButton = uibutton(app.UIFigure, 'push');
            app.MoveDobotButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDobotButtonPushed, true);
            app.MoveDobotButton.Text = 'Move Dobot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end